classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Image            matlab.ui.control.Image
        Label            matlab.ui.control.Label
        Title            matlab.ui.control.Label
        ResultLabel      matlab.ui.control.Label
        AnalyseButton    matlab.ui.control.Button
        LoadimageButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function ButtonLoad(app, event)
            global I
            [fName, dirName] = uigetfile('*.bmp;*.tif;*.jpg;*.tiff;*.png');
            jpg = fullfile(dirName, fName);
            image = imread(jpg);
            I = imresize(image, [224 224]);
            if(size(I, 3) == 1)
                I = cat(3, I, I, I);
                I = double(I);
            end
            app.Image.ImageSource = image;

            set(app.AnalyseButton,'Enable','on');
            text = '';
            textLabel = sprintf('%s', text);
            app.Label.Text = textLabel;
            app.Label
        end

        % Button pushed function: AnalyseButton
        function ButtonAnalyse(app, event)
            global I
            cnn = load('results\sgdm\layers6\cnn.mat');
            cnn = cnn.net;
            YPred = classify(cnn, I);
            YPred = double(YPred);
            x = (predict(cnn,I))*100;
            if(YPred == 1)
                text = sprintf('Apple',x(1));
            end
            if(YPred == 2)
                text = sprintf('Banana',x(2));
            end
            if(YPred == 3)
                text = sprintf('Broccoli',x(3));
            end
            if(YPred == 4)
                text = sprintf('Carrots',x(4));
            end
            if(YPred == 5)
                text = sprintf('Cauliflower',x(5));
            end
            if(YPred == 6)
                text = sprintf('Chili',x(6));
            end
            if(YPred == 7)
                text = sprintf('Cocconut',x(7));
            end
            if(YPred == 8)
                text = sprintf('Cucumber',x(8));
            end
            if(YPred == 9)
                text = sprintf('Custard Apple',x(9));
            end
            if(YPred == 10)
                text = sprintf('Dates',x(10));
            end
            if(YPred == 11)
                text = sprintf('Dragon Fruit',x(11));
            end
            if(YPred == 12)
                text = sprintf('Egg',x(12));
            end
            if(YPred == 13)
                text = sprintf('Garlic',x(13));
            end
            if(YPred == 14)
                text = sprintf('Grape',x(14));
            end
            if(YPred == 15)
                text = sprintf('Green Lemon',x(15));
            end
            if(YPred == 16)
                text = sprintf('Jackfruit',x(16));
            end
            if(YPred == 17)
                text = sprintf('Kiwi',x(17));
            end
            if(YPred == 18)
                text = sprintf('Mango',x(18));
            end
            if(YPred == 19)
                text = sprintf('Okra',x(19));
            end
            if(YPred == 20)
                text = sprintf('Onion',x(20));
            end
            if(YPred == 21)
                text = sprintf('Orange',x(21));
            end
            if(YPred == 22)
                text = sprintf('Papaya',x(22));
            end
            if(YPred == 23)
                text = sprintf('Peanut',x(23));
            end
            if(YPred == 24)
                text = sprintf('Pineapple',x(24));
            end
            if(YPred == 25)
                text = sprintf('Pomegranate',x(25));
            end
            if(YPred == 26)
                text = sprintf('Star Fruit',x(26));
            end
            if(YPred == 27)
                text = sprintf('Strawberry',x(27));
            end
            if(YPred == 28)
                text = sprintf('Sweet Potato',x(28));
            end
            if(YPred == 29)
                text = sprintf('Watermelon',x(29));
            end
            if(YPred == 30)
                text = sprintf('White Mushroom',x(30));
            end
            textLabel = sprintf('%s', text);
            app.Label.Text = textLabel;
            app.Label
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadimageButton = uibutton(app.UIFigure, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoad, true);
            app.LoadimageButton.Text = 'Load image';
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonAnalyse, true);
            app.AnalyseButton.Enable = 'off';
            app.AnalyseButton.Text = 'Analyse';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Load a photo fruit or vegetable';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end